classdef version1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        L1Label             matlab.ui.control.Label
        L1EditField         matlab.ui.control.NumericEditField
        L2EditFieldLabel    matlab.ui.control.Label
        L2EditField         matlab.ui.control.NumericEditField
        L3EditFieldLabel    matlab.ui.control.Label
        L3EditField         matlab.ui.control.NumericEditField
        L4EditFieldLabel    matlab.ui.control.Label
        L4EditField         matlab.ui.control.NumericEditField
        Button              matlab.ui.control.Button
        ButtonGroup         matlab.ui.container.ButtonGroup
        L3Button            matlab.ui.control.RadioButton
        thetathetaButton    matlab.ui.control.RadioButton
        L3Button_2          matlab.ui.control.RadioButton
        Label               matlab.ui.control.Label
        Button_2            matlab.ui.control.Button
        L301EditFieldLabel  matlab.ui.control.Label
        L301EditField       matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            global L1 L2 L3 L4 theta2 theta2_tmp;
            L1=app.L1EditField.Value;
            L2=app.L2EditField.Value;
            L3=app.L3EditField.Value;
            L3point=app.L301EditField.Value;
            L4=app.L4EditField.Value;
            Lsum=L1+L2+L3+L4;
            Lmax=max(L1,L2);
            Lmax=max(L3,Lmax);
            Lmax=max(L4,Lmax);
            Lmin=min(L1,L2);
            Lmin=min(Lmin,L3);
            Lmin=min(Lmin,L4);
            if ((Lsum)<=2*Lmax) 
                warndlg("输入长度无法构成4杆机构");
            else 
                pi=3.1415926;
                step_coe=0.01;
                %双摇
                if (((Lmin+Lmax)*2>Lsum) || (L3==Lmin))
                    %warndlg("双摇杆");
                    app.Label.Text="双摇杆";
                    Min_theta2=acos(((L2+L3)*(L2+L3)+L1*L1-L4*L4)/(2*(L2+L3)*L1));
                    Max_theta2=acos((L2*L2+L1*L1-(L4+L3)*(L4+L3))/(2*L2*L1));
                
                else 
                    %双曲 和 单曲中L2为最短杆
                    if ((L1==Lmin) || (L2==Lmin))
                        %warndlg("双曲杆");
                        app.Label.Text="双曲杆";
                        Min_theta2=0;
                        Max_theta2=2*pi;
                    else
                        %单曲 L2为摇杆
                        %warndlg("单曲杆");
                        app.Label.Text="单曲杆";
                        Min_theta2=acos(((L1+L4)*(L1+L4)+L2*L2-L3*L3)/(2*(L1+L4)*L2));
                        Max_theta2=acos(((L1-L4)*(L1-L4)+L2*L2-L3*L3)/(2*(L1-L4)*L2));
                 
                    end
                end
                
                theta2=[0:step_coe:1]*(Max_theta2-Min_theta2)+Min_theta2;
                theta34=zeros(length(theta2),2);
                x0=[1,1];
                for m=1:length(theta2)
                    theta2_tmp=theta2(m);
                    theta34(m,:)=fsolve(@func_fsolve,x0);
                    disp(theta34(m,:));
                end
                
                if app.L3Button.Value==true
                    figure(2)
                    hold on
                    for m=1:length(theta2)
                        x1=L2*cos(theta2(m));
                        x2=x1+L3*cos(theta34(m,1));
                        y1=L2*sin(theta2(m));
                        y2=y1+L3*sin(theta34(m,1));
                        plot([x1,x2],[y1,y2])
                    end
                    hold off
                    xlabel('x')
                    ylabel('y')    
                else 
                    if app.thetathetaButton.Value==true
                        figure(3)
                        hold on 
                        plot(theta2,theta34(:,1))
                        plot(theta2,theta34(:,2))
                        hold off
                        xlabel('theta2 rad')
                        ylabel('theta3/4 rad')
                    else 
                        %disp(length(theta2));
                        %disp(length(theta34(:,1)));
                        %disp(length(theta34(:,2)));
                        %T=table(theta2',theta34(:,1),theta34(:,2));
                        %writetable(T,date+"theta2-3-4.xlsx");
                        figure(4)
                        x1=L2*cos(theta2);
                        x2=x1+L3*cos(theta34(:,1))';
                        y1=L2*sin(theta2);
                        y2=y1+L3*sin(theta34(:,1))';
                        x=(1-L3point)*x1+L3point*x2;
                        y=(1-L3point)*y1+L3point*y2;
                        plot(x,y);
                        xlabel('x')
                        ylabel('y')
                    end
                end
            end
        end

        % Callback function
        function Button_2Pushed(app, event)
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed2(app, event)
            image=imread("test.png");
            figure(1)
            imshow(image);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.L1Label = uilabel(app.UIFigure);
            app.L1Label.Text = 'L1(固定边)';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L4EditFieldLabel = uilabel(app.UIFigure);
            app.L4EditFieldLabel.Text = 'L4';
            app.L4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '运行';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '关系选择器';
            app.L3Button = uiradiobutton(app.ButtonGroup);
            app.L3Button.Text = 'L3';
            app.L3Button.Value = true;
            app.thetathetaButton = uiradiobutton(app.ButtonGroup);
            app.thetathetaButton.Text = 'theta-theta';
            app.L3Button_2 = uiradiobutton(app.ButtonGroup);
            app.L3Button_2.Text = 'L3比例点轨迹';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '连杆系统';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_2.Text = '显示示例';
            app.L301EditFieldLabel = uilabel(app.UIFigure);
            app.L301EditFieldLabel.Text = 'L3比例点（0-1）';
            app.L301EditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = version1_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end